classdef Equilizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudiomataUIFigure  matlab.ui.Figure
        LoadButton         matlab.ui.control.Button
        PlayButton         matlab.ui.control.Switch
        UIAxes             matlab.ui.control.UIAxes
        HzSliderLabel      matlab.ui.control.Label
        Slider60Hz         matlab.ui.control.Slider
        HzLabel            matlab.ui.control.Label
        Slider170Hz        matlab.ui.control.Slider
        HzLabel_2          matlab.ui.control.Label
        Slider310Hz        matlab.ui.control.Slider
        HzLabel_3          matlab.ui.control.Label
        Slider600Hz        matlab.ui.control.Slider
        khzLabel           matlab.ui.control.Label
        Slider3000Hz       matlab.ui.control.Slider
        kHzLabel_5         matlab.ui.control.Label
        Slider6000Hz       matlab.ui.control.Slider
        kHzLabel_4         matlab.ui.control.Label
        Slider12000Hz      matlab.ui.control.Slider
        kHzLabel_3         matlab.ui.control.Label
        Slider14000Hz      matlab.ui.control.Slider
        kHzLabel_2         matlab.ui.control.Label
        Slider16000Hz      matlab.ui.control.Slider
        kHzLabel           matlab.ui.control.Label
        Slider1000Hz       matlab.ui.control.Slider
        dBLabel            matlab.ui.control.Label
        dBLabel_2          matlab.ui.control.Label
        Fres               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Song;
        songinfo;
        Fs;
        Music;
        newload='nosong';  %start state
        MRead;
        Left;
        Right;
        Modified;
        Fmat=[60 170 310 600 1000 3000 6000 12000 14000 16000];
        
    end
    
    methods (Access = private)
        
        function graph_M(app)
            plot(app.UIAxes,[0 0],[0 0]);
            h = animatedline(app.UIAxes);
            i=0;
            
            while(length(app.PlayButton.Value)==4)
                
                if(length(app.PlayButton.Value)==4)
                    addpoints(h,i,app.Modified(app.Music.CurrentSample()));
                    i=i+1;
                    
                    %                     app.UIaxes.axis([i-10,i+10,-1,1])
                    drawnow
                end
            end
        end
        
        
        function Myfilter(app)
            pause(app.Music);
            
            sample=app.Music.CurrentSample();
            R=app.Right;
            L=app.Left;
            [b a]=get_low_shelving_filter(app.Slider60Hz.Value,0.6,60,app.Fs);
            [b1 a1]=get_peak_filter(app.Slider170Hz.Value,0.6,170,app.Fs);
            b=conv(b,b1);
            a=conv(a,a1);
            [b1 a1]=get_peak_filter(app.Slider310Hz.Value,0.6,310,app.Fs);
            b=conv(b,b1);
            a=conv(a,a1);
            [b1 a1]=get_peak_filter(app.Slider1000Hz.Value,0.6,1000,app.Fs);
            b=conv(b,b1);
            a=conv(a,a1);
            [b1 a1]=get_peak_filter(app.Slider3000Hz.Value,0.6,3000,app.Fs);
            b=conv(b,b1);
            a=conv(a,a1);
            [b1 a1]=get_peak_filter(app.Slider6000Hz.Value,0.6,6000,app.Fs);
            b=conv(b,b1);
            a=conv(a,a1);
            [b1 a1]=get_peak_filter(app.Slider12000Hz.Value,0.6,12000,app.Fs);
            b=conv(b,b1);
            a=conv(a,a1);
            [b1 a1]=get_peak_filter(app.Slider14000Hz.Value,0.6,14000,app.Fs);
            b=conv(b,b1);
            a=conv(a,a1);
            [b1 a1]=get_high_shelving_filter(app.Slider16000Hz.Value,0.6,16000,app.Fs);
            b=conv(b,b1);
            a=conv(a,a1);
            
            R=filter(b,a,R);
            L=filter(b,a,L);
            M=[L R];
            %             fvtool(app.UIAxes,dsp.BiquadFilter('SOSMatrix',SOS1))
            app.Modified=M;
            app.Music=audioplayer(M,app.Fs);
            play(app.Music,sample)
            app.Fplot()
        end
        
        function Fplot(app)
            x=app.Modified;
            L=length(x);
            NFFT = 2^nextpow2(L);
            Y = fft(x,NFFT)/L;
            f = app.Fs/2*linspace(0,1,NFFT/2+1);
            plot(app.Fres,f,2*abs(Y(1:NFFT/2+1)));
            
        end
        
        function sliderHzEnable(app)
            app.Slider60Hz.Enable=true;
            app.Slider170Hz.Enable=true;
            app.Slider310Hz.Enable=true;
            app.Slider600Hz.Enable=true;
            app.Slider1000Hz.Enable=true;
            app.Slider3000Hz.Enable=true;
            app.Slider6000Hz.Enable=true;
            app.Slider12000Hz.Enable=true;
            app.Slider14000Hz.Enable=true;
            app.Slider16000Hz.Enable=true;
        end
        function sliderHzDisable(app)
            app.Slider60Hz.Enable=false;
            app.Slider170Hz.Enable=false;
            app.Slider310Hz.Enable=false;
            app.Slider600Hz.Enable=false;
            app.Slider1000Hz.Enable=false;
            app.Slider3000Hz.Enable=false;
            app.Slider6000Hz.Enable=false;
            app.Slider12000Hz.Enable=false;
            app.Slider14000Hz.Enable=false;
            app.Slider16000Hz.Enable=false;
        end
    end


    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile({'*.mp3';'*.wav';'*.*'},'Choose a mp3 file','C:\Users\safim\Music');
            app.Song= fullfile(path,file);
            app.newload='songloaded';                                    %file loaded state
            app.PlayButton.Enable=true;
            app.sliderHzEnable();
            figure(app.AudiomataUIFigure)
            app.MRead = audioread(app.Song);
            app.Modified=app.MRead;
            app.songinfo = audioinfo(app.Song);
            app.Fs= app.songinfo.SampleRate;
            app.Left=app.MRead(:,1);
            app.Right=app.MRead(:,2);
            app.Fplot()
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if (length(value) == 4)
                %                 if(app.newload ~= 'nosong')
                app.LoadButton.Enable=false;
                if(app.newload == 'pausedsong')
                    resume(app.Music);
                    app.sliderHzEnable();
                else
                    M = app.MRead;
                    
                    app.Music=audioplayer(M,app.Fs);
                    play(app.Music)
                    %                     end
                end
                
            elseif (length(value) == 5)
                %                 if(app.newload ~= 'nosong')
                app.newload='pausedsong';  %pause state
                pause(app.Music)
                app.sliderHzDisable()
                app.LoadButton.Enable=true;
            end
            %             end
            app.graph_M()
            
        end

        % Value changed function: Slider60Hz
        function Slider60HzValueChanged(app, event)
            value = app.Slider60Hz.Value;
            app.Myfilter();
            
        end

        % Value changed function: Slider12000Hz
        function Slider12000HzValueChanged(app, event)
            value = app.Slider12000Hz.Value;
            app.Myfilter();
        end

        % Value changed function: Slider1000Hz
        function Slider1000HzValueChanged(app, event)
            value = app.Slider1000Hz.Value;
            app.Myfilter();
        end

        % Value changed function: Slider14000Hz
        function Slider14000HzValueChanged(app, event)
            value = app.Slider14000Hz.Value;
            app.Myfilter();
        end

        % Value changed function: Slider16000Hz
        function Slider16000HzValueChanged(app, event)
            value = app.Slider16000Hz.Value;
            app.Myfilter();
        end

        % Value changed function: Slider170Hz
        function Slider170HzValueChanged(app, event)
            value = app.Slider170Hz.Value;
            app.Myfilter();
        end

        % Value changed function: Slider3000Hz
        function Slider3000HzValueChanged(app, event)
            value = app.Slider3000Hz.Value;
            app.Myfilter();
        end

        % Value changed function: Slider310Hz
        function Slider310HzValueChanged(app, event)
            value = app.Slider310Hz.Value;
            app.Myfilter();
        end

        % Value changed function: Slider6000Hz
        function Slider6000HzValueChanged(app, event)
            value = app.Slider6000Hz.Value;
            app.Myfilter();
        end

        % Value changed function: Slider600Hz
        function Slider600HzValueChanged(app, event)
            value = app.Slider600Hz.Value;
            app.Myfilter();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudiomataUIFigure = uifigure;
            app.AudiomataUIFigure.Name = 'Audiomata';
            app.LoadButton = uibutton(app.AudiomataUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlayButton = uiswitch(app.AudiomataUIFigure, 'slider');
            app.PlayButton.Items = {'Pause', 'Play'};
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Value = 'Pause';
            app.UIAxes = uiaxes(app.AudiomataUIFigure);
            xlabel(app.UIAxes, 'Time Plot')
            app.UIAxes.LineStyleOrderIndex = 2;
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.UIAxes.XScale = 'log';
            app.HzSliderLabel = uilabel(app.AudiomataUIFigure);
            app.HzSliderLabel.Text = '60 Hz';
            app.Slider60Hz = uislider(app.AudiomataUIFigure);
            app.Slider60Hz.Limits = [-19.9 19.9];
            app.Slider60Hz.MajorTicks = [-19.9 19.9];
            app.Slider60Hz.MajorTickLabels = {'', ''};
            app.Slider60Hz.ValueChangedFcn = createCallbackFcn(app, @Slider60HzValueChanged, true);
            app.Slider60Hz.MinorTicks = [];
            app.Slider60Hz.Enable = 'off';
            app.HzLabel = uilabel(app.AudiomataUIFigure);
            app.HzLabel.Text = '170 Hz';
            app.Slider170Hz = uislider(app.AudiomataUIFigure);
            app.Slider170Hz.Limits = [-19.9 19.9];
            app.Slider170Hz.MajorTicks = [-19.9 19.9];
            app.Slider170Hz.MajorTickLabels = {'', ''};
            app.Slider170Hz.ValueChangedFcn = createCallbackFcn(app, @Slider170HzValueChanged, true);
            app.Slider170Hz.MinorTicks = [];
            app.Slider170Hz.Enable = 'off';
            app.HzLabel_2 = uilabel(app.AudiomataUIFigure);
            app.HzLabel_2.Text = '310 Hz';
            app.Slider310Hz = uislider(app.AudiomataUIFigure);
            app.Slider310Hz.Limits = [-19.9 19.9];
            app.Slider310Hz.MajorTicks = [-19.9 19.9];
            app.Slider310Hz.MajorTickLabels = {'', ''};
            app.Slider310Hz.ValueChangedFcn = createCallbackFcn(app, @Slider310HzValueChanged, true);
            app.Slider310Hz.MinorTicks = [];
            app.Slider310Hz.Enable = 'off';
            app.HzLabel_3 = uilabel(app.AudiomataUIFigure);
            app.HzLabel_3.Text = '600 Hz';
            app.Slider600Hz = uislider(app.AudiomataUIFigure);
            app.Slider600Hz.Limits = [-19.9 19.9];
            app.Slider600Hz.MajorTicks = [-19.9 19.9];
            app.Slider600Hz.MajorTickLabels = {'', ''};
            app.Slider600Hz.ValueChangedFcn = createCallbackFcn(app, @Slider600HzValueChanged, true);
            app.Slider600Hz.MinorTicks = [];
            app.Slider600Hz.Enable = 'off';
            app.khzLabel = uilabel(app.AudiomataUIFigure);
            app.khzLabel.Text = '3 khz';
            app.Slider3000Hz = uislider(app.AudiomataUIFigure);
            app.Slider3000Hz.Limits = [-19.9 19.9];
            app.Slider3000Hz.MajorTicks = [-19.9 19.9];
            app.Slider3000Hz.MajorTickLabels = {'', ''};
            app.Slider3000Hz.ValueChangedFcn = createCallbackFcn(app, @Slider3000HzValueChanged, true);
            app.Slider3000Hz.MinorTicks = [];
            app.Slider3000Hz.Enable = 'off';
            app.kHzLabel_5 = uilabel(app.AudiomataUIFigure);
            app.kHzLabel_5.Text = '6 kHz';
            app.Slider6000Hz = uislider(app.AudiomataUIFigure);
            app.Slider6000Hz.Limits = [-19.9 19.9];
            app.Slider6000Hz.MajorTicks = [-19.9 19.9];
            app.Slider6000Hz.MajorTickLabels = {'', ''};
            app.Slider6000Hz.ValueChangedFcn = createCallbackFcn(app, @Slider6000HzValueChanged, true);
            app.Slider6000Hz.MinorTicks = [];
            app.Slider6000Hz.Enable = 'off';
            app.kHzLabel_4 = uilabel(app.AudiomataUIFigure);
            app.kHzLabel_4.Text = '12 kHz';
            app.Slider12000Hz = uislider(app.AudiomataUIFigure);
            app.Slider12000Hz.Limits = [-19.9 19.9];
            app.Slider12000Hz.MajorTicks = [-19.9 19.9];
            app.Slider12000Hz.MajorTickLabels = {'', ''};
            app.Slider12000Hz.ValueChangedFcn = createCallbackFcn(app, @Slider12000HzValueChanged, true);
            app.Slider12000Hz.MinorTicks = [];
            app.Slider12000Hz.Enable = 'off';
            app.kHzLabel_3 = uilabel(app.AudiomataUIFigure);
            app.kHzLabel_3.Text = '14 kHz';
            app.Slider14000Hz = uislider(app.AudiomataUIFigure);
            app.Slider14000Hz.Limits = [-19.9 19.9];
            app.Slider14000Hz.MajorTicks = [-19.9 19.9];
            app.Slider14000Hz.MajorTickLabels = {'', ''};
            app.Slider14000Hz.ValueChangedFcn = createCallbackFcn(app, @Slider14000HzValueChanged, true);
            app.Slider14000Hz.MinorTicks = [];
            app.Slider14000Hz.Enable = 'off';
            app.kHzLabel_2 = uilabel(app.AudiomataUIFigure);
            app.kHzLabel_2.Text = '16 kHz';
            app.Slider16000Hz = uislider(app.AudiomataUIFigure);
            app.Slider16000Hz.Limits = [-19.9 19.9];
            app.Slider16000Hz.MajorTicks = [-19.9 19.9];
            app.Slider16000Hz.MajorTickLabels = {'', ''};
            app.Slider16000Hz.ValueChangedFcn = createCallbackFcn(app, @Slider16000HzValueChanged, true);
            app.Slider16000Hz.MinorTicks = [];
            app.Slider16000Hz.Enable = 'off';
            app.kHzLabel = uilabel(app.AudiomataUIFigure);
            app.kHzLabel.Text = '1k Hz';
            app.Slider1000Hz = uislider(app.AudiomataUIFigure);
            app.Slider1000Hz.Limits = [-19.9 19.9];
            app.Slider1000Hz.MajorTicks = [-19.9 19.9];
            app.Slider1000Hz.MajorTickLabels = {'', ''};
            app.Slider1000Hz.ValueChangedFcn = createCallbackFcn(app, @Slider1000HzValueChanged, true);
            app.Slider1000Hz.MinorTicks = [];
            app.Slider1000Hz.Enable = 'off';
            app.dBLabel = uilabel(app.AudiomataUIFigure);
            app.dBLabel.Text = '19.9 dB';
            app.dBLabel_2 = uilabel(app.AudiomataUIFigure);
            app.dBLabel_2.Text = '-19.9 dB';
            app.Fres = uiaxes(app.AudiomataUIFigure);
            xlabel(app.Fres, 'DFT')
            app.Fres.XTick = [];
            app.Fres.XTickLabel = '';
            app.Fres.YTick = [];
            app.Fres.YTickLabel = {'[ ]'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Equilizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudiomataUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudiomataUIFigure)
        end
    end
end